classdef ControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Main                            matlab.ui.container.Tab
        MainGrid                        matlab.ui.container.GridLayout
        LeftGrid                        matlab.ui.container.GridLayout
        LiveGrid                        matlab.ui.container.GridLayout
        LiveLoad                        matlab.ui.control.NumericEditField
        SetLoad                         matlab.ui.control.Slider
        SetpowerLabel                   matlab.ui.control.Label
        ResetLoad                       matlab.ui.control.Button
        InputGrid                       matlab.ui.container.GridLayout
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        InitButton                      matlab.ui.control.Button
        T1Spinner                       matlab.ui.control.Spinner
        AmbienttemperatureSpinnerLabel  matlab.ui.control.Label
        RightGrid                       matlab.ui.container.GridLayout
        RightGridBottom                 matlab.ui.container.GridLayout
        SpeedDisplay                    matlab.ui.control.NumericEditField
        SpeedrpmLabel                   matlab.ui.control.Label
        PRDisplay                       matlab.ui.control.NumericEditField
        PREditFieldLabel                matlab.ui.control.Label
        RightGridTop                    matlab.ui.container.GridLayout
        PowerDisplay                    matlab.ui.control.NumericEditField
        PowerkWLabel                    matlab.ui.control.Label
        CompressorAxes                  matlab.ui.control.UIAxes
        PowerMonitor                    matlab.ui.control.UIAxes
        Plant                           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SchemeAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Initialized = false
        PollTimer
        BlkProd; PortProd         % cached location of P_el_prod
        BlkReq; PortReq           % cached location of P_el_req_limited
        Tbuf  = []                % time history
        Pprod = []                % produced power (kW)
        Preq  = []                % setpoint (kW)
        PageStart = 0             % left edge of the current 10 s page
        LineProd                  % handle to the produced-power line
        LineReq                   % handle to the setpoint line
        Pdes = 80
        Scheme                    % struct of label text handles
        SchemeBlk = struct()      % cached {block,port} for each temperature tag
        LabelTick = 0             % throttles label refresh
        EtaLbl = struct()         % text handles for the efficiency labels
    end
    
    
    methods (Access = private)
        
        function [blk, prt] = findSignal(app, tag)
            mdl = 'microturbine_model_v2';
            mf  = {'MatchFilter', @Simulink.match.activeVariants};
            g  = find_system(mdl,'FindAll','on','LookUnderMasks','on','FollowLinks','on', ...
                     mf{:}, 'BlockType','Goto','GotoTag',tag);
            ph = get_param(g(1),'PortHandles');
            ln = get_param(ph.Inport,'Line');
            blk = get_param(ln,'SrcBlockHandle');
            prt = get_param(get_param(ln,'SrcPortHandle'),'PortNumber');
        
            % If the signal comes straight out of a subsystem, that boundary is
            % virtual (no RuntimeObject). Walk inward to the real source block.

            for depth = 1:20
                if ~strcmp(get_param(blk,'BlockType'),'SubSystem'), break; end
                op = find_system(blk,'SearchDepth',1,'FindAll','on','LookUnderMasks','on', ...
                                 'BlockType','Outport','Port',num2str(prt));

                if isempty(op), break; end
                ln  = get_param(get_param(op(1),'PortHandles').Inport,'Line');
                blk = get_param(ln,'SrcBlockHandle');
                prt = get_param(get_param(ln,'SrcPortHandle'),'PortNumber');
            end
        end

        function v = readVal(~, blk, prt)
            % read a block's current output value while the sim runs
            v = NaN;
            try
                rto = get_param(blk,'RuntimeObject');
                d   = rto.OutputPort(prt).Data;
                v   = d(1);
                catch
            end
        end
        
        function onPoll(app)
            mdl = 'microturbine_model_v2';
            try
                status = get_param(mdl,'SimulationStatus');
                if strcmp(status,'stopped')
                    app.StopButtonPushed([]);   % run really ended -> reset the UI
                    return
                end
                if ~strcmp(status,'running')
                    return                       % still compiling/initializing: wait
                end
        
                t = get_param(mdl,'SimulationTime');
                p = app.readVal(app.BlkProd, app.PortProd)/1000;   % W -> kW

                % Skip the very first read: at t=0 the power block hasn't
                % computed its output yet for this run, so RuntimeObject
                % returns its initial 0 -> a spurious rise-from-zero.
                if t <= 0 || ~isfinite(p)
                    return
                end

                app.PowerDisplay.Value = p;                               
                if isfield(app.SchemeBlk,'n')
                    app.SpeedDisplay.Value = app.readVal(app.SchemeBlk.n{:});
                end
                if isfield(app.SchemeBlk,'PR')
                    app.PRDisplay.Value = app.readVal(app.SchemeBlk.PR{:});
                end

                app.Tbuf(end+1)  = t;
                app.Pprod(end+1) = p;
                app.Preq(end+1)  = app.readVal([mdl '/P_el_target'], 1);
        
                keep = app.Tbuf >= t - 20;       % rolling window
                W = 20;                          % page width (seconds)
                page = floor(t / W) * W;         % left edge of the page t falls in
                
                if page ~= app.PageStart         % crossed into a new page?
                    app.PageStart = page;
                    xlim(app.PowerMonitor, [page, page + W]);   % jump the axis once
                    keep = app.Tbuf >= page;                    % drop the old page's data
                    app.Tbuf=app.Tbuf(keep); app.Pprod=app.Pprod(keep); app.Preq=app.Preq(keep);
                end
                set(app.LineProd, 'XData', app.Tbuf, 'YData', app.Pprod);
                set(app.LineReq,  'XData', app.Tbuf, 'YData', app.Preq);

                app.LabelTick = app.LabelTick + 1;
                if mod(app.LabelTick,3) == 0 && ~isempty(app.Scheme)
                    map = {'T2','T2'; 'T2pp','T2pp'; 'T3','T3'; ...
                           'T4','T4_prime'; 'T5','T5'; ...
                           'Twout','T_HW'; 'T6','T6'};
                    cap = {'T2 comp out','T2pp rec out','T3 CC out','T4 turb out', ...
                           'T5 rec out','water out','T6 HRB out'};
                    for k = 1:size(map,1)
                        tg = map{k,2};
                        if isfield(app.SchemeBlk, tg)
                            bp = app.SchemeBlk.(tg);
                            v  = app.readVal(bp{1}, bp{2}) - 273.15;   % K -> °C
                            app.Scheme.(map{k,1}).String = ...
                                sprintf('%s\n%.0f °C', cap{k}, v);
                        end
                    end

                    etaTags = {'eta_el','eta_CHP'};
                    for k = 1:numel(etaTags)
                        tg = etaTags{k};
                        if isfield(app.SchemeBlk,tg) && isfield(app.EtaLbl,tg) && isvalid(app.EtaLbl.(tg))
                            v = app.readVal(app.SchemeBlk.(tg){:});
                            if isfinite(v) && v <= 1.5           % fraction -> percent
                                v = 100*v;
                            end
                            app.EtaLbl.(tg).String = sprintf('%s\n%.1f %%', tg, v);
                        end
                    end

                end

                drawnow limitrate

            catch ME
                if ~isempty(app.PollTimer) && isvalid(app.PollTimer)
                    stop(app.PollTimer);
                end
                uialert(app.RightGrid, ME.message, 'Live update error');
            end
        end

        function UIFigureCloseRequest(app, event)
            try stop(app.PollTimer); delete(app.PollTimer); catch, end
            try set_param('microturbine_model_v2','SimulationCommand','stop'); catch, end
            delete(app)
        end

        function pushSetpoint(~, v)
            % write the requested-power value (kW) onto the P_el_target Constant
            try
                set_param('microturbine_model_v2/P_el_target', 'Value', num2str(v));
            catch
                % model not loaded yet (knob turned before Start) -> ignore
            end
        end

        function makeEtaLabels(app)
            % (Re)create the two efficiency labels in the top-left of the scheme.
            % Must run after every drawPlantScheme() call, since that redraws the axes.
            ax    = app.SchemeAxes;
            names = {'eta_el','eta_CHP'};
            ypos  = [0.97 0.86];               % normalized, top-left corner
        
            for k = 1:numel(names)
                h = text(ax, 0, 0, sprintf('%s\n-- %%', names{k}));
                set(h, 'Units','normalized', ...
                       'Position',[0.03 ypos(k) 0], ...
                       'Interpreter','none', ...          % keeps "eta_el" from subscripting
                       'HorizontalAlignment','left', ...
                       'VerticalAlignment','top', ...
                       'FontName','Consolas', 'FontSize',14, ...
                       'Color',[1 1 1], ...
                       'BackgroundColor',[0.15 0.15 0.15], ...
                       'EdgeColor',[0.6 0.6 0.6], ...
                       'Margin',10, 'Clipping','off');
                app.EtaLbl.(names{k}) = h;
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDir = fileparts(which(class(app)));           % folder the .mlapp lives in
            addpath(fullfile(appDir,'MATLAB functions'));
            addpath(fullfile(appDir,'Allegati'));


            % center a 4:3 window on screen
            scr = get(groot,'ScreenSize');
            h = scr(4) - 80;
            w = 4/3 * h;
            x = scr(1) + (scr(3) - w)/2;
            y = scr(2) + (scr(4) - h)/2 + 10;
            app.UIFigure.Position = [x y w h];
        
            % make the tab group fill the whole window (fixes the corner-jam)
            app.UIFigure.AutoResizeChildren = 'off';
            app.TabGroup.Units    = 'normalized';
            app.TabGroup.Position = [0 0 1 1];

            app.Scheme = drawPlantScheme(app.SchemeAxes);
            app.makeEtaLabels();

            app.SpeedDisplay.ValueDisplayFormat = '%.0f';
            app.PRDisplay.ValueDisplayFormat    = '%.1f';
            app.PowerDisplay.ValueDisplayFormat = '%.1f';
    
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.Initialized
        uialert(app.UIFigure,'Press Initialize first.','Not ready');
                return
            end

    app.StartButton.Enable = 'off';
    app.InitButton.Enable  = 'off';

    mdl = 'microturbine_model_v2';
    try
        load_system(mdl);                                    % make sure it's loaded
        set_param(mdl,'SimulationMode','normal');
        set_param(mdl,'EnablePacing','on','PacingRate', 1);   % run at ~real time
        set_param(mdl, 'StopTime', 'inf');
        set_param(mdl,'SimulationCommand','start');          % start in background
    catch ME
        uialert(app.RightGrid, ME.message, 'Could not start');
        return
    end

    app.Tbuf = []; app.Pprod = []; app.Preq = [];

    cla(app.PowerMonitor);
    hold(app.PowerMonitor,'on');
    app.LineProd = plot(app.PowerMonitor, NaN, NaN, '-', 'LineWidth',1.3);
    app.LineReq  = plot(app.PowerMonitor, NaN, NaN, '-', 'LineWidth',1.3);
    hold(app.PowerMonitor,'off');
    legend(app.PowerMonitor, {'P_el','setpoint'}, ...
           'Location','southeast','Interpreter','none');
    ylim(app.PowerMonitor, [28 90]);
    xlim(app.PowerMonitor, [0 20]);
    app.PageStart = 0;

    [app.BlkProd, app.PortProd] = app.findSignal('P_el_prod');
    [app.BlkReq,  app.PortReq ] = app.findSignal('P_req_lim');

    for tg = {'T2','T2pp','T3','T4_prime','T5', 'T_HW', 'T6',...
            'n', 'PR', 'eta_el', 'eta_CHP'}
        try
            [b,p] = app.findSignal(tg{1});
            app.SchemeBlk.(tg{1}) = {b,p};
        catch
        end
    end

    if isempty(app.PollTimer) || ~isvalid(app.PollTimer)
        app.PollTimer = timer('ExecutionMode','fixedSpacing', ...
            'Period', 0.1, 'BusyMode','drop', 'TimerFcn',@(~,~) app.onPoll());
    end
    start(app.PollTimer);

    app.StatusLamp.Color   = [0.10 0.80 0.20];   % green = running
    app.StopButton.Enable  = 'on';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.PollTimer) && isvalid(app.PollTimer) && strcmp(app.PollTimer.Running,'on')
                stop(app.PollTimer);
            end
        
            try set_param('microturbine_model_v2','SimulationCommand','stop'); catch, end
            app.StatusLamp.Color   = [0.20 0.55 0.95];   % blue = ready
            app.StartButton.Enable = 'on';
            app.StopButton.Enable  = 'off';
            app.InitButton.Enable  = 'on';
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
        % show a "busy" spinner while the script runs
            dlg = uiprogressdlg(app.UIFigure,'Title','Initializing', ...
                'Message','Running data_v2.m ...','Indeterminate','on');
            c = onCleanup(@() close(dlg));
        
            try
                assignin('base','appCompAxes', app.CompressorAxes);

                % 1) hand our chosen T1 (deg C) to the MATLAB workspace
                assignin('base','T1_app', app.T1Spinner.Value);
        
                % 2) run data_v2 from its own folder so its addpath() lines resolve
                initPath = which('data_v2');
                if isempty(initPath)
                    error('Cannot find data_v2.m. Put it in this folder.');
                end
                oldFolder = cd(fileparts(initPath));
                cRestore  = onCleanup(@() cd(oldFolder));   % switch back automatically
                evalin('base','data_v2');                   % <-- runs your script

                app.Scheme = drawPlantScheme(app.SchemeAxes);
                app.makeEtaLabels();

                app.Scheme.T1.String = sprintf('T1 inlet\n%.1f °C', app.T1Spinner.Value);

                Tcw = evalin('base','T_cold_water');          % °C, constant
                app.Scheme.Twin.String = sprintf('water in\n%.0f °C', Tcw);
        
                % 3) Mark the app as initialized
                app.StatusLamp.Color = [0.20 0.55 0.95];   % blue = ready
                app.Initialized = true;

                % --- size the setpoint spinner from the design point ---
                P_el_min = ceil( evalin('base','P_el_min')/1e3 * 100) / 100;   % W -> kW, round up
                P_el_max = floor(evalin('base','P_el_max')/1e3 * 100) / 100;   % W -> kW, round down
                app.Pdes = evalin('base','P_start')/1e3;
                
                app.SetLoad.Limits = [P_el_min, P_el_max];
                app.LiveLoad.Limits = [P_el_min, P_el_max];
                % Slider ticks
                lo = ceil(P_el_min/10)*10;    % first multiple of 10 at/above the min
                hi = floor(P_el_max/10)*10;   % last multiple of 10 at/below the max
                app.SetLoad.MajorTicks = lo:10:hi;         % e.g. 50 60 70 80
                app.SetLoad.MinorTicks = ceil(P_el_min):2:floor(P_el_max);   % subtle in-between marks
                app.SetLoad.Value  = min(max(app.Pdes, P_el_min), P_el_max);

                app.SetLoad.Value = app.Pdes;
                app.pushSetpoint(app.Pdes);
                app.LiveLoad.Value = app.Pdes;
        
            catch ME
                uialert(app.UIFigure, ME.message, 'Initialization failed');
                app.Initialized = false;
            end
        end

        % Button pushed function: ResetLoad
        function ResetLoadButtonPushed(app, event)
            app.SetLoad.Value = app.Pdes;
            app.LiveLoad.Value = app.Pdes;
            app.pushSetpoint(app.Pdes);
        end

        % Value changing function: SetLoad
        function SetLoadValueChanging(app, event)
            app.pushSetpoint(event.Value);
            app.LiveLoad.Value = event.Value;
        end

        % Value changed function: LiveLoad
        function LiveLoadValueChanged(app, event)
            app.pushSetpoint(event.Value);      % command the model
            app.SetLoad.Value = event.Value;    % move the slider to match
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.MainGrid = uigridlayout(app.Main);
            app.RightGrid = uigridlayout(app.MainGrid);
            app.PowerMonitor = uiaxes(app.RightGrid);
            title(app.PowerMonitor, 'Electrical power output')
            xlabel(app.PowerMonitor, 'Time [s]')
            ylabel(app.PowerMonitor, 'Power [kW]')
            app.CompressorAxes = uiaxes(app.RightGrid);
            title(app.CompressorAxes, 'Live compressor map')
            xlabel(app.CompressorAxes, 'X')
            ylabel(app.CompressorAxes, 'Y')
            zlabel(app.CompressorAxes, 'Z')
            app.RightGridTop = uigridlayout(app.RightGrid);
            app.PowerkWLabel = uilabel(app.RightGridTop);
            app.PowerkWLabel.Text = 'Power [kW]';
            app.PowerDisplay = uieditfield(app.RightGridTop, 'numeric');
            app.PowerDisplay.Editable = 'off';
            app.RightGridBottom = uigridlayout(app.RightGrid);
            app.PREditFieldLabel = uilabel(app.RightGridBottom);
            app.PREditFieldLabel.Text = 'PR';
            app.PRDisplay = uieditfield(app.RightGridBottom, 'numeric');
            app.PRDisplay.Editable = 'off';
            app.SpeedrpmLabel = uilabel(app.RightGridBottom);
            app.SpeedrpmLabel.Text = 'Speed [rpm]';
            app.SpeedDisplay = uieditfield(app.RightGridBottom, 'numeric');
            app.SpeedDisplay.Editable = 'off';
            app.LeftGrid = uigridlayout(app.MainGrid);
            app.InputGrid = uigridlayout(app.LeftGrid);
            app.AmbienttemperatureSpinnerLabel = uilabel(app.InputGrid);
            app.AmbienttemperatureSpinnerLabel.Text = 'Ambient temperature';
            app.T1Spinner = uispinner(app.InputGrid);
            app.T1Spinner.Step = 0.5;
            app.T1Spinner.Limits = [-10 35];
            app.T1Spinner.Value = 15;
            app.InitButton = uibutton(app.InputGrid, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Initialize';
            app.StartButton = uibutton(app.InputGrid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.InputGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.StatusLampLabel = uilabel(app.InputGrid);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.InputGrid);
            app.LiveGrid = uigridlayout(app.LeftGrid);
            app.ResetLoad = uibutton(app.LiveGrid, 'push');
            app.ResetLoad.ButtonPushedFcn = createCallbackFcn(app, @ResetLoadButtonPushed, true);
            app.ResetLoad.Text = 'Design';
            app.SetpowerLabel = uilabel(app.LiveGrid);
            app.SetpowerLabel.Text = 'Set load [kW]';
            app.SetLoad = uislider(app.LiveGrid);
            app.SetLoad.ValueChangingFcn = createCallbackFcn(app, @SetLoadValueChanging, true);
            app.LiveLoad = uieditfield(app.LiveGrid, 'numeric');
            app.LiveLoad.ValueChangedFcn = createCallbackFcn(app, @LiveLoadValueChanged, true);
            app.Plant = uitab(app.TabGroup);
            app.Plant.Title = 'Plant';
            app.GridLayout = uigridlayout(app.Plant);
            app.SchemeAxes = uiaxes(app.GridLayout);
            title(app.SchemeAxes, 'Title')
            xlabel(app.SchemeAxes, 'X')
            ylabel(app.SchemeAxes, 'Y')
            zlabel(app.SchemeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end